classdef gui1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        Image9                matlab.ui.control.Image
        Image8                matlab.ui.control.Image
        Image7                matlab.ui.control.Image
        Image5                matlab.ui.control.Image
        Image4                matlab.ui.control.Image
        Image3                matlab.ui.control.Image
        catsspeciesdetectionprogramLabel  matlab.ui.control.Label
        ResultEditField       matlab.ui.control.EditField
        ResultEditFieldLabel  matlab.ui.control.Label
        RunButton             matlab.ui.control.Button
        UploadImageButton     matlab.ui.control.Button
        UIAxes                matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        im % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIAxes.Visible="off";
            app.im = load("C:\Users\acer\Documents\MATLAB\tN.mat");
        end

        % Button pushed function: UploadImageButton
        function UploadImageButtonPushed(app, event)
            filetype = {'*.jpg;*.tif;*.png;*.gif','All lmage Files'};
            [f,p] = uigetfile(filetype);

            if(ischar(p))
                fname = [p f];
                app.im = imread(fname);
                imagesc(app.UIAxes,app.im);
            end
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
x= load('tN.mat');
I = app.im;
I = imresize(I,[224 224]);

[YPred,probs] = classify(x.trainedNetwork_1,I);
label = YPred;
app.ResultEditField.Value = "  It's a " + string(label)+"  "+num2str(100*max(probs),3)+"%";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UploadImageButton = uibutton(app.UIFigure, 'push');
            app.UploadImageButton.ButtonPushedFcn = createCallbackFcn(app, @UploadImageButtonPushed, true);
            app.UploadImageButton.Text = 'Upload Image';
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.ResultEditFieldLabel = uilabel(app.UIFigure);
            app.ResultEditFieldLabel.Text = 'Result : ';
            app.ResultEditField = uieditfield(app.UIFigure, 'text');
            app.catsspeciesdetectionprogramLabel = uilabel(app.UIFigure);
            app.catsspeciesdetectionprogramLabel.Text = 'cat''s species detection program';
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.ImageSource = 'OIP__1_-removebg-preview.png';
            app.Image4 = uiimage(app.UIFigure);
            app.Image4.ImageSource = '0BDF1683-F12B-4236-9951-4C40C777F0FB.png';
            app.Image5 = uiimage(app.UIFigure);
            app.Image5.ImageSource = '510F0FE9-88DB-4F86-89A0-0C2A28C9C080.png';
            app.Image7 = uiimage(app.UIFigure);
            app.Image7.ImageSource = '5A559C39-A223-48CD-8C82-1A082A758332.png';
            app.Image8 = uiimage(app.UIFigure);
            app.Image8.ImageSource = '832AC06E-EEF3-47D4-A23A-78285E9A9ABA.png';
            app.Image9 = uiimage(app.UIFigure);
            app.Image9.ImageSource = 'R__1_-removebg-preview.png';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui1

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end